classdef integratedTerminalPreferences < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        HelpButton   matlab.ui.control.StateButton
        ResetButton  matlab.ui.control.Button
        TextArea     matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        preferences % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.preferences = controller.integratedterminalpreferences();
            app.TextArea.Value = app.preferences.read();
        end

        % Value changing function: TextArea
        function TextAreaValueChanging(app, event)
            app.preferences.write(event.Value);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.preferences.reset();
            app.TextArea.Value = app.preferences.read();
        end

        % Value changed function: HelpButton
        function HelpButtonValueChanged(app, event)
            if app.HelpButton.Value
                app.TextArea.Value = app.preferences.gethelp();
                app.HelpButton.FontColor = [0.2,0.2,0.2];
            else
                app.TextArea.Value = app.preferences.read();
                app.HelpButton.FontColor = [1.00,1.00,1.00];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'images', 'integratedTerminalPreferences.png');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangingFcn = createCallbackFcn(app, @TextAreaValueChanging, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.HelpButton = uibutton(app.UIFigure, 'state');
            app.HelpButton.ValueChangedFcn = createCallbackFcn(app, @HelpButtonValueChanged, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = integratedTerminalPreferences

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end